classdef DialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogAppUIFigure            matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        ex10fortenfoldLabel          matlab.ui.control.Label
        SampleGainEditField          matlab.ui.control.NumericEditField
        SampleGainEditFieldLabel     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        StimVoltageEditField         matlab.ui.control.NumericEditField
        StimVoltageEditFieldLabel    matlab.ui.control.Label
        StimPositionDropDown         matlab.ui.control.DropDown
        StimPositionDropDownLabel    matlab.ui.control.Label
        SexDropDown                  matlab.ui.control.DropDown
        SexDropDownLabel             matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        exm123456Label               matlab.ui.control.Label
        ParticipantIDEditField       matlab.ui.control.EditField
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        DialogAppState        % Current state of the MainApp
        MainAppObj          % Object for MainApp
        MeasAppObj          % Object for MeasApp
    end
    
    
    methods (Access = public)
        
        function setDialogAppViewState(app, dlgstate)
            switch dlgstate
                case 'NewParticipant'
                    app.ParticipantIDEditField.Enable = "on";
                    app.AgeEditField.Enable = "on";
                    app.SexDropDown.Enable = "on";
                    app.StimPositionDropDown.Enable = "on";
                    app.StimVoltageEditField.Enable = "on";
                    app.SampleGainEditField.Enable = "on";
                case 'NewCondition'
                    app.ParticipantIDEditField.Enable = "off";
                    app.AgeEditField.Enable = "off";
                    app.SexDropDown.Enable = "off";
                    app.StimPositionDropDown.Enable = "on";
                    app.StimVoltageEditField.Enable = "on";
                    app.SampleGainEditField.Enable = "on";
                case 'Measure'
                    app.ParticipantIDEditField.Enable = "off";
                    app.AgeEditField.Enable = "off";
                    app.SexDropDown.Enable = "off";
                    app.StimPositionDropDown.Enable = "off";
                    app.StimVoltageEditField.Enable = "off";
                    app.SampleGainEditField.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, dlgstate, pid, age, sex)
            % Store main app (DataDisplay) in property for CloseRequestFcn
            % to use
            app.MainAppObj = caller;

            % Process inputs
            app.DialogAppState = dlgstate;

            setDialogAppViewState(app, dlgstate)

            switch dlgstate
                case 'NewParticipant'
                    % Blank
                case 'NewCondition'
                    app.ParticipantIDEditField.Value = pid;
                    app.AgeEditField.Value = age;
                    app.SexDropDown.Value = sex;
                case 'Measure'
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Dsiable OK button while MeasApp is open
            app.OKButton.Enable = "off";

            % Set values
            pid = app.ParticipantIDEditField.Value;
            age = app.AgeEditField.Value;
            sex = app.SexDropDown.Value;
            spos = app.StimPositionDropDown.Value;
            svol = app.StimVoltageEditField.Value;
            sgain = app.SampleGainEditField.Value;

            if isempty(pid) || ...
                    isempty(age) || ...
                    isempty(sex) || ...
                    isempty(spos) || ...
                    isempty(svol) || ...
                    isempty(sgain)
                
                % Caution
                msgbox('There seem to be some missing values.', 'Invalid!', "error")
                
                % Enable OK button while MeasApp is open
                app.OKButton.Enable = "on";
            else
                

                % Set view
                app.DialogAppState = 'Measure';
                setDialogAppViewState(app, app.DialogAppState);

                % Call MeasApp with input values
                app.MeasAppObj = MeasApp(app, pid, age, sex, spos, svol, sgain);
                
            end
        end

        % Close request function: DialogAppUIFigure
        function DialogAppUIFigureCloseRequest(app, event)
            % Enable Menu in main app (DataDisplay)
            app.MainAppObj.FileDataMenu.Enable ="on";

            % Delete the MeasApp
            delete(app.MeasAppObj)
            % Delete the dialog app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogAppUIFigure = uifigure('Visible', 'off');
            app.DialogAppUIFigure.Name = 'Dailog';
            app.DialogAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppUIFigureCloseRequest, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.DialogAppUIFigure);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.DialogAppUIFigure, 'text');
            app.exm123456Label = uilabel(app.DialogAppUIFigure);
            app.exm123456Label.Text = '(ex: "m123456")';
            app.AgeEditFieldLabel = uilabel(app.DialogAppUIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.DialogAppUIFigure, 'numeric');
            app.AgeEditField.Limits = [0 200];
            app.SexDropDownLabel = uilabel(app.DialogAppUIFigure);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.DialogAppUIFigure);
            app.SexDropDown.Items = {'Male', 'Female', 'Others', 'Rather not state'};
            app.SexDropDown.Value = 'Male';
            app.StimPositionDropDownLabel = uilabel(app.DialogAppUIFigure);
            app.StimPositionDropDownLabel.Text = 'Stim Position';
            app.StimPositionDropDown = uidropdown(app.DialogAppUIFigure);
            app.StimPositionDropDown.Items = {'Elbow', 'Wrist', 'Knee'};
            app.StimPositionDropDown.Value = 'Elbow';
            app.StimVoltageEditFieldLabel = uilabel(app.DialogAppUIFigure);
            app.StimVoltageEditFieldLabel.Text = 'Stim Voltage';
            app.StimVoltageEditField = uieditfield(app.DialogAppUIFigure, 'numeric');
            app.StimVoltageEditField.Limits = [0 150];
            app.VLabel = uilabel(app.DialogAppUIFigure);
            app.VLabel.Text = '[V]';
            app.SampleGainEditFieldLabel = uilabel(app.DialogAppUIFigure);
            app.SampleGainEditFieldLabel.Text = 'Sample Gain';
            app.SampleGainEditField = uieditfield(app.DialogAppUIFigure, 'numeric');
            app.SampleGainEditField.Value = 1000;
            app.ex10fortenfoldLabel = uilabel(app.DialogAppUIFigure);
            app.ex10fortenfoldLabel.Text = '(ex: "10" for ten-fold)';
            app.OKButton = uibutton(app.DialogAppUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.DialogAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogAppUIFigure)
        end
    end
end