classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                 matlab.ui.Figure
        FileDataMenu                 matlab.ui.container.Menu
        createANewFile               matlab.ui.container.Menu
        loadAnExistingData           matlab.ui.container.Menu
        addANewMeasurement           matlab.ui.container.Menu
        saveData                     matlab.ui.container.Menu
        AnalysisMenu                 matlab.ui.container.Menu
        AveragedWaveformMenu         matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        VariableNameEditField        matlab.ui.control.EditField
        VariableNameEditFieldLabel   matlab.ui.control.Label
        ParticipantExpInfoLabel      matlab.ui.control.Label
        Panel2_4                     matlab.ui.container.Panel
        SexEditField                 matlab.ui.control.EditField
        SexEditFieldLabel            matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        ParticipantIDEditField       matlab.ui.control.EditField
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ExperimentInfoPanel          matlab.ui.container.Panel
        DataIDListBox                matlab.ui.control.ListBox
        DataIDListBoxLabel           matlab.ui.control.Label
        SampleDurationListBox        matlab.ui.control.ListBox
        SampleDurationListBoxLabel   matlab.ui.control.Label
        SampleRateListBox            matlab.ui.control.ListBox
        SampleRateListBoxLabel       matlab.ui.control.Label
        StimVoltageListBox           matlab.ui.control.ListBox
        StimVoltageListBoxLabel      matlab.ui.control.Label
        StimPositionListBox          matlab.ui.control.ListBox
        StimPositionListBoxLabel     matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        PlotTab                      matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        DataTab                      matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        displayedIndices
        CurrentState
        DialogAppObj
        varName
    end
    
    properties (Access = public)
        Data
    end
    
    methods (Access = private)
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            % Initialization
            tempIndices = logical(ones(size(app.Data, 1), 1));

            % Rate
            if ~isempty(app.SampleRateListBox.Value)
                value = app.SampleRateListBox.Value;
                tempIndices = all([tempIndices, ismember(app.Data.sample_rate_hz, str2num(char(value)))], 2);
            end

            % Duration
            if ~isempty(app.SampleDurationListBox.Value)
                value = app.SampleDurationListBox.Value;
                tempIndices = all([tempIndices, ismember(app.Data.sample_duration_sec, str2num(char(value)))], 2);
            end

            % Stim Position
            if ~isempty(app.StimPositionListBox.Value)
                value = app.StimPositionListBox.Value;
                tempIndices = all([tempIndices, ismember(app.Data.stim_position, value)], 2);
            end

            % Stim Voltage
            if ~isempty(app.StimVoltageListBox.Value)
                value = app.StimVoltageListBox.Value;
                tempIndices = all([tempIndices, ismember(app.Data.stim_voltage, str2num(char(value)))], 2);
            end

            % Data ID
            if ~isempty(app.DataIDListBox.Value)
                value_tmp = app.DataIDListBox.Value;
                value = zeros([1, length(value_tmp)]);
                for ii = 1:length(value_tmp)
                    value(ii) = str2double(strtok(value_tmp{ii}));
                end
                tempIndices = all([tempIndices, ismember(app.Data.data_id, value)], 2);
                % % tempIndices = all([tempIndices, ismember(app.Data.data_id, str2num(char(value)))], 2);
            end

            app.displayedIndices = tempIndices;

        end
               
        
        function plotERP(app)

            % Start with a fresh plot
                cla(app.UIAxes);
                hold(app.UIAxes, "on");

            if height(app.UITable.Data) > 0 && ...
                    length(unique(app.UITable.Data.sample_rate_hz)) == 1 && ...
                    length(unique(app.UITable.Data.sample_duration_sec)) == 1
            
                % x: time
                rate = app.UITable.Data.sample_rate_hz(1);
                dur = app.UITable.Data.sample_duration_sec(1);
                x = linspace(0, dur, rate*dur) * 1000; % in [ms]

                % y: amp
                gain = app.UITable.Data.sample_gain(1);
                y = cell2mat(app.UITable.Data.data) / gain * 1000; % in [mV]

                % Plot
                plot(app.UIAxes, x, y);
             
            end
        end
        
        function initializeMainAppUIComponents(app)
            app.UITable.Data = app.Data(:, 4:end-1);
    
            app.ParticipantIDEditField.Value = '';  % Pariticipant ID
            app.AgeEditField.Value = 0;             % Age
            app.SexEditField.Value = '';            % Sex

            % Create lists for conditions
            app.DataIDListBox.Items = {};           % Data ID
            app.StimPositionListBox.Items = {};     % Stim Position
            app.StimVoltageListBox.Items = {};      % Stim Voltage
            app.SampleRateListBox.Items = {};       % Sampling Rate in Hz
            app.SampleDurationListBox.Items = {};   % Sampling Duration in Sec

            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            app.displayedIndices = ones([size(app.Data,1),1]);

            % Refresh plot
            plotERP(app);
        end
        
        function saveDataFnc(app)
            [file,path,indx] = uiputfile('*.mat', 'Select File to Write', append(app.Data.participantID(1)));
            if indx == 1
                assignin("base", app.varName, app.Data);
                evalin("base", ['save("' path file '", "' app.varName '")']);
            end            
        end
    end

    methods (Access = public)
        
        function updateMainAppUIComponents(app)
            app.UITable.Data = app.Data(:, 4:end-1);
    
            app.ParticipantIDEditField.Value = string(app.Data.participantID(1));
            app.AgeEditField.Value = app.Data.age(1);
            app.SexEditField.Value = string(app.Data.sex(1));

            % Data ID with some info for the list box
            data_id_lbox = cell([1, length(app.Data.data_id)]);
            for ii = 1:length(app.Data.data_id)
                data_id_lbox{ii} = ...      % 1xn cell: e.g., "1 (Elbow, v10, g10000)"
                    [num2str(app.Data.data_id(ii)), ' (', ...       % data_id: double
                    char(app.Data.stim_position(ii)), ...           % stim_position: categorical
                    ', v', num2str(app.Data.stim_voltage(ii)), ...  % stim_voltage: double
                    ', g' num2str(app.Data.sample_gain(ii)), ')'];  % sample_gain: double
            end

            % Create lists for conditions
            app.DataIDListBox.Items = data_id_lbox; %strsplit(num2str(app.Data.data_id'));
            app.StimPositionListBox.Items = unique(app.Data.stim_position);
            app.StimVoltageListBox.Items = unique(strsplit(num2str(app.Data.stim_voltage')));
            app.SampleRateListBox.Items = unique(strsplit(num2str(app.Data.sample_rate_hz')));
            app.SampleDurationListBox.Items = unique(strsplit(num2str(app.Data.sample_duration_sec')));

            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            app.displayedIndices = ones([size(app.Data,1),1]);

            % Refresh plot
            plotERP(app);

            % update expdata in the base workspace
            assignin("base", app.varName, app.Data);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.MainUIFigure,"center");
            
            app.varName = app.VariableNameEditField.Value;

            % Clear a variable for expdata in the base workspace
            expression = ['clearvars ' app.varName];
            evalin("base", expression);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MainUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {591, 591};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Cell selection callback: UITable
        function refreshplot(app, event)
%             Genders = app.SelectedGenders;
%             Colors = app.SelectedColors;
%             
%             % Start with a fresh plot
%             cla(app.UIAxes)
%             hold(app.UIAxes,'on')
%             app.Histogram = gobjects(0);
%             
%             % Select relevant segment of data
%             xdata = app.Data.Weight;
%             ydata = app.Data.(app.BloodPressureSwitch.Value);
%             
%             % Filter the data according to the controls
%             filterData(app);
%             
%             % Create either a scatter plot or histogram, based on selection
%             switch app.ButtonGroup.SelectedObject.Text
%                 
%                 case 'Scatter'
%                     % Build a scatter plot for each selected gender
%                     for ii = 1:length(Genders)
%                         selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
%                         scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
%                     end
%                     annotateScatterPlot(app)
%                     
%                 case 'Histogram'
%                     % Build a histogram for each selected gender
%                     for ii = 1:length(Genders)
%                         selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
%                         NBins = numhistbins(app,ydata(selectedpatients));
%                         h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
%                         h.EdgeColor = Colors{ii};
%                         h.FaceColor = Colors{ii};
%                         app.Histogram = [app.Histogram h];
%                     end
%                     annotateHistogram(app)
%                     
%             end
%             
%             % Update the table to show only the data that satisfies the controls
%             app.UITable.Data = app.Data(app.displayedIndices,:);
%             drawnow;
        end

        % Value changed function: SampleRateListBox
        function SampleRateListBoxValueChanged(app, event)

            filterData(app);

            app.UITable.Data = app.Data(app.displayedIndices, 4:end-1);
            drawnow;

            plotERP(app);
        end

        % Value changed function: SampleDurationListBox
        function SampleDurationListBoxValueChanged(app, event)
   
            filterData(app);

            app.UITable.Data = app.Data(app.displayedIndices, 4:end-1);
            drawnow;

            plotERP(app);
        end

        % Value changed function: StimPositionListBox
        function StimPositionListBoxValueChanged(app, event)

            filterData(app);

            app.UITable.Data = app.Data(app.displayedIndices, 4:end-1);
            drawnow;

            plotERP(app);
        end

        % Value changed function: StimVoltageListBox
        function StimVoltageListBoxValueChanged(app, event)

            filterData(app);

            app.UITable.Data = app.Data(app.displayedIndices, 4:end-1);
            drawnow;

            plotERP(app);
        end

        % Value changed function: DataIDListBox
        function DataIDListBoxValueChanged(app, event)
            
            filterData(app);

            app.UITable.Data = app.Data(app.displayedIndices, 4:end-1);

            plotERP(app);
            
        end

        % Menu selected function: loadAnExistingData
        function loadAnExistingDataMenuSelected(app, event)
            [filename, pathname, idx] = uigetfile('*.mat', 'Select File to Load');
            figure(app.MainUIFigure) 

            if idx ~= 0
                load(fullfile(pathname, filename), app.varName);
    
                % Store the data in a table and display it in one of the App's tabs.
                app.Data = eval(app.varName);

                % Load data to the base workspace
                expression = ['load("' pathname filename '", "' app.varName '");'];
                evalin("base", expression)

                % Update UI components
                updateMainAppUIComponents(app);
            end

        end

        % Menu selected function: createANewFile
        function createANewFileMenuSelected(app, event)
            if evalin('base', ['exist("' app.varName '")'])
                % Confirm data save
                answer = questdlg('Do you want to save it before new recording?', ...
	                                'There is data existing.', ...
	                                'Yes','No', 'Yes');
                % Handle response
                if strcmp(answer, 'Yes')
                    saveDataFnc(app);
                end
            end

            % Disable Menu while dialog is open.
            app.FileDataMenu.Enable = "off";

            % Clear data
            app.Data = table();

            % Initialize UI components
            initializeMainAppUIComponents(app);

            % Clear a variable for expdata in the base workspace
            expression = ['clearvars ' app.varName];
            evalin("base", expression);

            % Open the dialog
            dlgstate = 'NewParticipant'; % daialog app state
            app.DialogAppObj= DialogApp(app, dlgstate, [], [], []);

        end

        % Menu selected function: addANewMeasurement
        function addANewMeasurementMenuSelected(app, event)
            if evalin('base', ['exist("' app.varName '")'])
                % Disable Menu while dialog is open.
                app.FileDataMenu.Enable = "off";
    
                % expinfo to be passed to the dialog:
                % participantID, age, sex
                pid = app.ParticipantIDEditField.Value;
                age = app.AgeEditField.Value;
                sex = app.SexEditField.Value;
    
                % Open the dialog with exp info
                dlgstate = 'NewCondition'; % dialog app state
                app.DialogAppObj = DialogApp(app, dlgstate, pid, age, sex);
            else
                % Message: "there are no loaded data"
                uialert(app.MainUIFigure, 'Please select data first.', 'No loaded data found!');
            end
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            if evalin('base', ['exist("' app.varName '")'])
                % Confirm data save
                answer = questdlg('Do you want to save it before closing app?', ...
	                                'There is data existing.', ...
	                                'Yes','No', 'Yes');
                % Handle response
                if strcmp(answer, 'Yes')
                    saveDataFnc(app);
                end
            end

            % Delete all apps
            try
                delete(app.DialogAppObj.MeasAppObj)
                delete(app.DialogAppObj)
                delete(app)
            catch exception
                if strcmp(exception.identifier, 'MATLAB:class:InvalidHandle')
                    delete(app.DialogAppObj)
                    delete(app)
                elseif strcmp(exception.identifier, 'MATLAB:structRefFromNonStruct')
                    delete(app)
                else
                    uialert(app.MainUIFigure, exception.message, exception.identifier)
                end
            end
                
        end

        % Menu selected function: saveData
        function saveDataMenuSelected(app, event)
            saveDataFnc(app);
        end

        % Menu selected function: AveragedWaveformMenu
        function AveragedWaveformMenuSelected(app, event)
            
            % Start with a fresh plot
                cla(app.UIAxes);
                hold(app.UIAxes, "on");

            if height(app.UITable.Data) == 0
                uialert(app.MainUIFigure, "Data is NOT selected. Please specify.", "Invalid!")
            elseif length(unique(app.UITable.Data.sample_rate_hz)) > 1 || ...
                    length(unique(app.UITable.Data.sample_duration_sec)) > 1
                uialert(app.MainUIFigure, "The numbers of sampling points do NOT match. Please specify the samplig rate and duration.", "Invalid!")
            else
            
                % x: time
                rate = app.UITable.Data.sample_rate_hz(1);
                dur = app.UITable.Data.sample_duration_sec(1);
                x = linspace(0, dur, rate*dur) * 1000; % in [ms]

                % y: amp
                gain = app.UITable.Data.sample_gain(1);
                y = cell2mat(app.UITable.Data.data) / gain * 1000; % in [mV]

                % y_avg
                y_avg = mean(y, 1);

                % Plot
                plot(app.UIAxes, x, y, 'k:', x, y_avg, 'r-o', 'LineWidth', 1);
             
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.MainUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileDataMenu = uimenu(app.MainUIFigure);
            app.FileDataMenu.Text = 'File/Data';
            app.createANewFile = uimenu(app.FileDataMenu);
            app.createANewFile.MenuSelectedFcn = createCallbackFcn(app, @createANewFileMenuSelected, true);
            app.createANewFile.Text = 'Create a new file (for a new participant)';
            app.loadAnExistingData = uimenu(app.FileDataMenu);
            app.loadAnExistingData.MenuSelectedFcn = createCallbackFcn(app, @loadAnExistingDataMenuSelected, true);
            app.loadAnExistingData.Text = 'Load an existing data';
            app.addANewMeasurement = uimenu(app.FileDataMenu);
            app.addANewMeasurement.MenuSelectedFcn = createCallbackFcn(app, @addANewMeasurementMenuSelected, true);
            app.addANewMeasurement.Text = 'Add new data to the loaded data (new measurement)';
            app.saveData = uimenu(app.FileDataMenu);
            app.saveData.MenuSelectedFcn = createCallbackFcn(app, @saveDataMenuSelected, true);
            app.saveData.Text = 'Save data';
            app.AnalysisMenu = uimenu(app.MainUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.AveragedWaveformMenu = uimenu(app.AnalysisMenu);
            app.AveragedWaveformMenu.MenuSelectedFcn = createCallbackFcn(app, @AveragedWaveformMenuSelected, true);
            app.AveragedWaveformMenu.Text = 'Averaged Waveform';
            app.GridLayout = uigridlayout(app.MainUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Participant';
            app.ParticipantIDEditFieldLabel = uilabel(app.Panel2_4);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.Panel2_4, 'text');
            app.ParticipantIDEditField.Editable = 'off';
            app.AgeEditFieldLabel = uilabel(app.Panel2_4);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel2_4, 'numeric');
            app.AgeEditField.Editable = 'off';
            app.SexEditFieldLabel = uilabel(app.Panel2_4);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.Panel2_4, 'text');
            app.SexEditField.Editable = 'off';
            app.ParticipantExpInfoLabel = uilabel(app.LeftPanel);
            app.ParticipantExpInfoLabel.Text = 'Participant/Exp Info';
            app.VariableNameEditFieldLabel = uilabel(app.LeftPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.LeftPanel, 'text');
            app.VariableNameEditField.Editable = 'off';
            app.VariableNameEditField.Value = 'expdata';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Time [ms]')
            ylabel(app.UIAxes, 'Amplitude [mV]')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Data ID'; 'Stim Position'; 'Stim Voltage [V]'; 'Sample Gain'; 'Sample Rate [Hz]'; 'Sample Duration [s]'; 'Data'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
            app.ExperimentInfoPanel = uipanel(app.RightPanel);
            app.ExperimentInfoPanel.Title = 'Experiment Info.';
            app.StimPositionListBoxLabel = uilabel(app.ExperimentInfoPanel);
            app.StimPositionListBoxLabel.Text = 'Stim Position';
            app.StimPositionListBox = uilistbox(app.ExperimentInfoPanel);
            app.StimPositionListBox.Items = {};
            app.StimPositionListBox.Multiselect = 'on';
            app.StimPositionListBox.ValueChangedFcn = createCallbackFcn(app, @StimPositionListBoxValueChanged, true);
            app.StimPositionListBox.Value = {};
            app.StimVoltageListBoxLabel = uilabel(app.ExperimentInfoPanel);
            app.StimVoltageListBoxLabel.Text = 'Stim Voltage';
            app.StimVoltageListBox = uilistbox(app.ExperimentInfoPanel);
            app.StimVoltageListBox.Items = {};
            app.StimVoltageListBox.Multiselect = 'on';
            app.StimVoltageListBox.ValueChangedFcn = createCallbackFcn(app, @StimVoltageListBoxValueChanged, true);
            app.StimVoltageListBox.Value = {};
            app.SampleRateListBoxLabel = uilabel(app.ExperimentInfoPanel);
            app.SampleRateListBoxLabel.Text = 'Sample Rate';
            app.SampleRateListBox = uilistbox(app.ExperimentInfoPanel);
            app.SampleRateListBox.Items = {};
            app.SampleRateListBox.ValueChangedFcn = createCallbackFcn(app, @SampleRateListBoxValueChanged, true);
            app.SampleRateListBox.Value = {};
            app.SampleDurationListBoxLabel = uilabel(app.ExperimentInfoPanel);
            app.SampleDurationListBoxLabel.Text = 'Sample Duration';
            app.SampleDurationListBox = uilistbox(app.ExperimentInfoPanel);
            app.SampleDurationListBox.Items = {};
            app.SampleDurationListBox.ValueChangedFcn = createCallbackFcn(app, @SampleDurationListBoxValueChanged, true);
            app.SampleDurationListBox.Value = {};
            app.DataIDListBoxLabel = uilabel(app.ExperimentInfoPanel);
            app.DataIDListBoxLabel.Text = 'Data ID';
            app.DataIDListBox = uilistbox(app.ExperimentInfoPanel);
            app.DataIDListBox.Items = {};
            app.DataIDListBox.Multiselect = 'on';
            app.DataIDListBox.ValueChangedFcn = createCallbackFcn(app, @DataIDListBoxValueChanged, true);
            app.DataIDListBox.Value = {};
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end