classdef MeasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasUIFigure                    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelLabel                  matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        RateSlider                      matlab.ui.control.Slider
        RateEdit                        matlab.ui.control.NumericEditField
        RatescanssLabel                 matlab.ui.control.Label
        ExcitationSourceDropDown        matlab.ui.control.DropDown
        ExcitationSourceLabel           matlab.ui.control.Label
        TerminalConfigDropDown          matlab.ui.control.DropDown
        TerminalConfigLabel             matlab.ui.control.Label
        CouplingDropDown                matlab.ui.control.DropDown
        CouplingLabel                   matlab.ui.control.Label
        RangeDropDown                   matlab.ui.control.DropDown
        RangeLabel                      matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        MeasurementTypeLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelLabel                    matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceLabel                     matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        CenterPanelLabel                matlab.ui.control.Label
        CaptureDataAxes                 matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TriggerChannelDropDown          matlab.ui.control.DropDown
        TriggerChannelLabel             matlab.ui.control.Label
        RightPanelLabel                 matlab.ui.control.Label
        CaptureDurationEditField        matlab.ui.control.NumericEditField
        CaptureDurationsEditFieldLabel  matlab.ui.control.Label
        TriggerDelayEditField           matlab.ui.control.NumericEditField
        TriggerDelaysEditFieldLabel     matlab.ui.control.Label
        TriggerLevelEditField           matlab.ui.control.NumericEditField
        TriggerLevelEditFieldLabel      matlab.ui.control.Label
        StatusText                      matlab.ui.control.Label
        CaptureButton                   matlab.ui.control.StateButton
        VariableNameEditField           matlab.ui.control.EditField
        VariableNameEditFieldLabel      matlab.ui.control.Label
        TriggerConditionDropDown        matlab.ui.control.DropDown
        TriggerConditionDropDownLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Analog Trigger App example
    % 2019/01/11 version 1.0 Andrei Ursache
    % 2019/12/21 version 1.1, AU, updated DAQ code from Session to DataAcquisition interface
    
    % Copyright 2019-2020 The MathWorks, Inc.
        
    properties (Access = private)
        DAQ                      % Handle to DAQ object
        DevicesInfo              % Array of devices that provide analog input voltage or audio input measurements
        DAQMeasurementTypes = {'Voltage','IEPE','Audio'};  % DAQ input measurement types supported by the app
        DAQSubsystemTypes = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        CapturePlotLine          % Handle to line plot of capture data
        CaptureTimestamps        % Captured data timestamps (s)
        CaptureData              % Captured data
        Timestamps = [];         % Latest timestamps values used for trigger detection
        Data = [];               % Latest data values used for trigger detection     
        TriggerDelay             % Trigger delay (s)
        CaptureDuration          % Capture duration (s)
        CurrentState = '';       % App current state
        scanData                 % timetable array for data scanned via a DAQ interface

        participantID
        age
        sex
        stim_position
        stim_voltage
        sample_gain
    end
    
    properties (Access = public)
        DialogApp               % DialogApp object
        MeasData
    end
    
    methods (Access = private)      
        
        function [items, itemsData] = getChannelPropertyOptions(~, subsystem, propertyName)
        %getChannelPropertyOptions Get options available for a DAQ channel property
        %  Returns items and itemsData for displaying options in a dropdown component.
        %   subsystem is the DAQ subsystem handle corresponding to the DAQ channel.
        %   propertyName is a channel property name as a character array, and can be
        %    'TerminalConfig', 'Coupling', or 'Range'.
        %   items is a cell array of possible property values, for example {'DC', 'AC'}.
        %   itemsData is [] (empty) for 'TerminalConfig' and 'Coupling', and is a cell array of
        %     available ranges for 'Range', for example {[-10 10], [-1 1]}.
            
            switch propertyName
                case 'TerminalConfig'
                    items = cellstr(string(subsystem.TerminalConfigsAvailable));
                    itemsData = [];
                case 'Coupling'
                    items = cellstr(string(subsystem.CouplingsAvailable));
                    itemsData = [];
                case 'Range'
                    numRanges = numel(subsystem.RangesAvailable);
                    items = strings(numRanges,1);
                    itemsData = cell(numRanges,1);
                    for ii = 1:numRanges
                        range = subsystem.RangesAvailable(ii);
                        items(ii) = sprintf('%.2f to %.2f', range.Min, range.Max);
                        itemsData{ii} = [range.Min range.Max];
                    end
                    items = cellstr(items);                    
                case 'ExcitationSource'
                    items = {'Internal','External','None'};
                    itemsData = [];
            end
        end
        
        
        function setAppViewState(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  state can be 'DevicesSlection', 'Configuration', 'Acquisition.Buffering', 
        %   'Acquisition.ReadyForCapture', 'Capture.LookingForTrigger', 
        %   'Capture.CapturingData', or 'Capture.CaptureComplete'.
        
            switch state                
                case 'DeviceSelection'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'on';
                    app.ChannelDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.ExcitationSourceDropDown.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.CaptureButton.Enable = 'off';
                    app.TriggerDelayEditField.Enable = 'off';
                    app.CaptureDurationEditField.Enable = 'on';
                    
                case 'Configuration'
                    app.RateEdit.Enable = 'on';
                    app.RateSlider.Enable = 'on';
                    app.DeviceDropDown.Enable = 'on';
                    app.ChannelDropDown.Enable = 'on';
                    app.MeasurementTypeDropDown.Enable = 'on';
                    app.RangeDropDown.Enable = 'on';
                    app.StartButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.CaptureButton.Enable = 'off';
                    app.CaptureButton.Value = 0;
                    app.StatusText.Text = '';
                    app.CaptureButton.Text = 'Capture';
                    app.TriggerDelayEditField.Enable = 'off';
                    app.CaptureDurationEditField.Enable = 'on';
                    app.StatusText.Text = 'Ready for Capture';

                    app.TriggerChannelDropDown.Enable = 'on';
                    app.TriggerConditionDropDown.Enable = 'on';
                    app.TriggerLevelEditField.Enable = 'on';

                    switch app.MeasurementTypeDropDown.Value
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                            app.ExcitationSourceDropDown.Enable = 'off';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'off';
                            app.CouplingDropDown.Enable = 'off';
                            app.ExcitationSourceDropDown.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                            app.ExcitationSourceDropDown.Enable = 'on';
                    end
                    
                case 'Capture.LookingForTrigger'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.ExcitationSourceDropDown.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.StatusText.Text = 'Waiting for trigger';
                    app.TriggerDelayEditField.Enable = 'off';
                    app.CaptureDurationEditField.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';

                    app.TriggerChannelDropDown.Enable = 'off';
                    app.TriggerConditionDropDown.Enable = 'off';
                    app.TriggerLevelEditField.Enable = 'off';
                    
                case 'Capture.CapturingData'
                    app.StatusText.Text = 'Trigger detected. Capturing';

                case 'Capture.CaptureComplete'
                    app.StatusText.Text = 'Capture completed';
                    
            end
        end
        
        function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes)
        %daqListSupportedDevices Get connected devices that support the specified subsystem and measurement types      
            
            % Detect all connected devices
            devices = daqlist;
            deviceinfo = devices.DeviceInfo;
            
            % Keep a subset of devices which have the specified subystem and measurement types
            deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes);
            deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);
            
        end
                
        function filteredDevices = daqFilterDevicesBySubsystem(~, devices, subsystemTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the subsystem types provided
            hasSubsystemArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasSubsystem = false;
                for jj = 1:numel(subsystemTypes)
                    hasSubsystem = hasSubsystem || ...
                        any(strcmp({devices(ii).Subsystems.SubsystemType}, subsystemTypes{jj}));
                end
                hasSubsystemArray(ii) = hasSubsystem;
            end
            filteredDevices = devices(hasSubsystemArray);
        end
        
        
        function filteredDevices = daqFilterDevicesByMeasurement(~, devices, measurementTypes)
        %daqFilterDevicesByMeasurement Filter DAQ device array by measurement type
        %  devices is a DAQ device info array
        %  measurementTypes is a cell array of measurement types, for example {'Voltage, 'Current'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the measurement types provided
            hasMeasurementArray = false(numel(devices), 1);
            
            % Go through each device and subsystem and see if it has any of the measurement types provided
            for ii = 1:numel(devices)
                % Get array of available subsystems for the current device
                subsystems = [devices(ii).Subsystems];
                hasMeasurement = false;
                for jj = 1:numel(subsystems)
                    % Get cell array of available measurement types for the current subsystem
                    measurements = subsystems(jj).MeasurementTypesAvailable;
                    for kk = 1:numel(measurementTypes)
                        hasMeasurement = hasMeasurement || ...
                            any(strcmp(measurements, measurementTypes{kk}));
                    end
                end
                hasMeasurementArray(ii) = hasMeasurement;
            end
            filteredDevices = devices(hasMeasurementArray);
        end
        
        
        function updateRateUIComponents(app)
        %updateRateUIComponents Updates UI with current rate and time window limits
            
            % Update UI to show the actual DAQ rate and limits
            value = app.DAQ.Rate;
            app.RateEdit.Limits = app.DAQ.RateLimit;
            app.RateSlider.Limits = app.DAQ.RateLimit;
            app.RateSlider.MajorTicks = [app.DAQ.RateLimit(1) app.DAQ.RateLimit(2)];
            app.RateSlider.MinorTicks = [];
            app.RateEdit.Value = value;
            app.RateSlider.Value = value;
            
        end
        
        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Clean-up after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is called from CloseRequestFcn
        %  of the app UIFigure.
        %   event is the event data of the UIFigure CloseRequestFcn callback.
        %   isAcquiring is a logical flag (true/false) corresponding to DAQ
        %   running state.
        
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQ)
                        delete(app.DAQ)
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
        end
        
    
        function completeCapture(app)
        %completeCapture Saves captured data to workspace variable and plots it
            
            % Extract capture data and timestamps
            app.CaptureData = app.scanData.Variables; 
            app.CaptureTimestamps = seconds(app.scanData.Time); 

            % Update captured data plot (one line for each acquisition channel)
            set(app.CapturePlotLine, 'XData', app.CaptureTimestamps, 'YData', app.CaptureData);
            app.CaptureDataAxes.XLim = [min(app.CaptureTimestamps), max(app.CaptureTimestamps)];

            % Update Data ID
            if isempty(app.DialogApp.MainAppObj.Data)
                data_id = 1;
            else
                data_id = max(app.DialogApp.MainAppObj.Data.data_id) + 1;
            end
            
            % Save captured data to a base workspace variable
            % For simplicity, validation of user input and checking whether a variable
            % with the same name already exists are not addressed in this example.
            % Get the variable name from UI text input
%             varName = genvarname(app.VariableNameEditField.Value);
            variablenames = {'participantID', 'age', 'sex',...
                                'data_id', 'stim_position', 'stim_voltage', ...
                                'sample_gain', 'sample_rate_hz', 'sample_duration_sec', ...
                                'data', 'sample_datetime'};
            T  = table(string(app.participantID), ...
                        app.age, ...
                        categorical(cellstr(app.sex)), ...
                        data_id, ...
                        categorical(cellstr(app.stim_position)), ...
                        app.stim_voltage, ...
                        app.sample_gain, ...
                        app.DAQ.Rate, ...
                        app.CaptureDuration, ...
                        {app.CaptureData'},...
                        string(datetime('now', 'Timezone', 'local', 'format', 'uuuu-MM-dd''T''HH:mm:ssZ')), ...
                        'VariableNames', variablenames);

            % Add the new data to the MainApp
            app.DialogApp.MainAppObj.Data = [app.DialogApp.MainAppObj.Data; T];

            % Update MainApp UI components
            updateMainAppUIComponents(app.DialogApp.MainAppObj);       
            
        end

        
        function updateChannelMeasurementComponents(app)
        %updateChannelMeasurementComponents Updates channel properties and measurement UI components
            measurementType = app.MeasurementTypeDropDown.Value;

            % Get selected DAQ device index (to be used with DaqDevicesInfo list)
            deviceIndex = app.DeviceDropDown.Value - 1;
            deviceID = app.DevicesInfo(deviceIndex).ID;
            vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                        
            % Get DAQ subsystem information (analog input or audio input)
            % Analog input or analog output subsystems are the first subsystem of the device
            subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
            
            % Delete existing DAQ object
            delete(app.DAQ);
            app.DAQ = [];
            
            % Create a new data acquisition object
            d = daq(vendor);
            addinput(d, deviceID, app.ChannelDropDown.Value, measurementType);
            
            % Store DAQ object handle in an app property
            app.DAQ = d;
            app.DAQ.Rate = app.DAQ.RateLimit(2);
            app.DAQ.DigitalTriggerTimeout = 30;

            % Depending on what device and measurement are selected, populate the UI channel properties
            switch subsystem.SubsystemType
                case 'AnalogInput'
                                        
                    % Populate dropdown with available channel 'TerminalConfig' options
                    app.TerminalConfigDropDown.Items = getChannelPropertyOptions(app, subsystem, 'TerminalConfig');
                    % Update UI with the actual channel property value
                    % (default value is not necessarily first in the list)
                    % DropDown Value must be set as a character array in MATLAB R2017b
                    app.TerminalConfigDropDown.Value = d.Channels(1).TerminalConfig;
                    app.TerminalConfigDropDown.Tag = 'TerminalConfig';
                    
                    % Populate dropdown with available channel 'Coupling' options
                    app.CouplingDropDown.Items =  getChannelPropertyOptions(app, subsystem, 'Coupling');
                    % Update UI with the actual channel property value
                    app.CouplingDropDown.Value = d.Channels(1).Coupling;
                    app.CouplingDropDown.Tag = 'Coupling';
                                        
                    switch measurementType
                        case 'IEPE'
                            % Populate dropdown with available channel 'ExcitationSource' options
                            app.ExcitationSourceDropDown.Items = getChannelPropertyOptions(app, subsystem, 'ExcitationSource');
                            app.ExcitationSourceDropDown.Value = d.Channels(1).ExcitationSource;
                            app.ExcitationSourceDropDown.Tag = 'ExcitationSource';
                        case 'Voltage'
                            app.ExcitationSourceDropDown.Items = {''};
                    end
                    
                    ylabel(app.CaptureDataAxes, 'Voltage [mV]')
                                        
                case 'AudioInput'
                    ylabel(app.CaptureDataAxes, 'Normalized amplitude')
            end
            
            % Update UI with current rate and time window limits
            updateRateUIComponents(app)

            % Set parameters as the updated components
            app.DAQ.Rate = app.RateEdit.Value;
                    
            % Populate dropdown with available 'Range' options
            [rangeItems, rangeItemsData] = getChannelPropertyOptions(app, subsystem, 'Range');
            app.RangeDropDown.Items = rangeItems;
            app.RangeDropDown.ItemsData = rangeItemsData;
            
            % Update UI with current channel 'Range'
            currentRange = d.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange.Min currentRange.Max];
            app.RangeDropDown.Tag = 'Range';

            app.DeviceDropDown.Items{1} = 'Deselect device';
                        
            % Configure the app view for device configuration state
            app.CurrentState = 'Configuration';
            setAppViewState(app, app.CurrentState);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, pid, age, sex, spos, svol, sgain)

            % Store calling app object
            app.DialogApp = caller;

            % Assignment of the input variables
            app.participantID = pid;
            app.age = age;
            app.sex = sex;
            app.stim_position = spos;
            app.stim_voltage = svol;
            app.sample_gain = sgain;

            % Configure the app view for device selection state
            app.CurrentState = 'DeviceSelection';
            setAppViewState(app, app.CurrentState);
            
            % Get connected devices that have the supported subsystem and measurement types
            deviceinfo = daqListSupportedDevices(app, app.DAQSubsystemTypes, app.DAQMeasurementTypes); % TODO: for the case when any device is NOT found.
            
            % Store DAQ device information (filtered list) into DaqDevicesInfo app property
            % This is used by other functions in the app
            app.DevicesInfo = deviceinfo;
            
            % Populate the device drop down list with cell array of composite device names (ID + model)
            % First element is "Select a device"
            deviceDescriptions = cellstr(string({deviceinfo.ID}') + " [" + string({deviceinfo.Model}') + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DeviceDropDown.ItemsData = 1:numel(deviceinfo)+1;           
            
            app.CapturePlotLine = plot(app.CaptureDataAxes, NaN, NaN);   
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MeasUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {552, 552, 552};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {552, 552};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x', 243};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Reset Data and Timestamps
            app.Data = [];
            app.Timestamps = [];

            % Set parameters
            app.CaptureDuration = app.CaptureDurationEditField.Value;

            try
                
                % Set trigger and configure the app for LookingForTrigger state
                app.CurrentState = 'Capture.LookingForTrigger';
                setAppViewState(app, app.CurrentState);
                [~, Tidx] = addtrigger(app.DAQ, "Digital", "StartTrigger", "External", app.TriggerChannelDropDown.Value); % TODO: List up available digital trigger input (Only PFI1 for USB-6000).
                
                % When the trigget is detected, update the state and read
                % the data
                app.scanData = read(app.DAQ, seconds(app.CaptureDuration));
                app.CurrentState = 'Capture.CapturingData';
                setAppViewState(app, app.CurrentState);

                % Capturing completed
                app.CurrentState = 'Capture.CaptureComplete';
                setAppViewState(app, app.CurrentState);
                completeCapture(app);
                removetrigger(app.DAQ, Tidx);

                % Update the current state
                app.CurrentState = 'Configuration';
                setAppViewState(app, app.CurrentState);
            catch exception
                if strcmp(exception.identifier, 'MATLAB:class:InvalidHandle')
                    uialert(app.MeasUIFigure, 'The operation has been canceled. ', 'Aborted. Try again!');
                elseif strcmp(exception.identifier, 'daq:Session:externalTriggerTimeout')
                    % When session timed out, remove the trigger.
                    uialert(app.MeasUIFigure, exception.message, 'Time out. Try again!');
                    removetrigger(app.DAQ, "Digital", Tidx);
                elseif strcmp(exception.identifier, 'nidaq:ni:NIDAQmxError89136')
                    uialert(app.MeasUIFigure, exception.message, 'Not supported. Try another trigger channel!')
                else
                    uialert(app.MeasUIFigure, exception.message, 'Start error');
                    removetrigger(app.DAQ, "Digital", Tidx);
                end
                app.CurrentState = 'Configuration';
                setAppViewState(app, app.CurrentState);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            updateChannelMeasurementComponents(app);

        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            
            if ~isempty(value)
                % Device index is offset by 1 because first element in device dropdown
                % is "Select a device" (not a device).
                deviceIndex = value-1 ;
                
                % Reset channel property options
                app.ChannelDropDown.Items = {''};
                app.MeasurementTypeDropDown.Items = {''};
                app.RangeDropDown.Items = {''};
                app.TerminalConfigDropDown.Items = {''};
                app.CouplingDropDown.Items = {''};
                app.ExcitationSourceDropDown.Items = {''};
                app.TriggerChannelDropDown.Items = {''};
                setAppViewState(app, 'DeviceSelection');

                
                % Delete DAQ object, as a new one will be created for the newly selected device
                delete(app.DAQ);
                app.DAQ = [];
                
                if deviceIndex > 0
                    % If a device is selected
                    
                    % Get subsystem information to update channel dropdown list and channel property options
                    % For devices that have an analog input or an audio input subsystem, this is the first subsystem
                    subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
                    app.ChannelDropDown.Items = cellstr(string(subsystem.ChannelNames));
                    
                    % Populate available measurement types for the selected device
                    app.MeasurementTypeDropDown.Items = intersect(app.DAQMeasurementTypes,...
                                subsystem.MeasurementTypesAvailable, 'stable');

                    % Get terminals (PFI channel) information to update
                    % trigger channel dropdown list
                    app.TriggerChannelDropDown.Items = app.DevicesInfo(deviceIndex).Terminals;

                    % Update channel and channel property options
                    updateChannelMeasurementComponents(app) 

                else
                    % If no device is selected

                    % Delete existing DAQ object
                    delete(app.DAQ);
                    app.DAQ = [];
                    
                    app.DeviceDropDown.Items{1} = 'Select a device';
                    
                    % Configure the app view for device selection state
                    app.CurrentState = 'DeviceSelection';
                    setAppViewState(app, app.CurrentState);
                end
            end            
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            
            updateChannelMeasurementComponents(app)
 
        end

        % Value changed function: MeasurementTypeDropDown
        function MeasurementTypeDropDownValueChanged(app, event)

            updateChannelMeasurementComponents(app)
  
        end

        % Value changed function: RateEdit, RateSlider
        function RateSliderValueChanged(app, event)
        % Shared callback for RateSlider and RateEdit
            
            value = event.Source.Value;
            app.DAQ.Rate = value;
            
            % Update UI with current rate and time window limits
            updateRateUIComponents(app)
        end

        % Value changing function: RateSlider
        function RateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RateEdit.Value = changingValue;            
        end

        % Value changed function: CouplingDropDown, 
        % ...and 3 other components
        function ChannelPropertyValueChanged(app, event)

            % Shared callback for RangeDropDown, TerminalConfigDropDown, CouplingDropDown, and ExcitationSourceDropDown.
            % This executes only for 'Voltage' measurement type, since for 'Audio' measurement
            % type Range never changes, and TerminalConfig and Coupling are disabled.
            
            value = event.Source.Value;
            
            % Set channel property to selected value
            % The channel property name was previously stored in the UI component Tag
            propertyName = event.Source.Tag;
            try
                set(app.DAQ.Channels(1), propertyName, value);
            catch exception
                % In case of error show it and revert the change
                uialert(app.MeasUIFigure, exception.message, 'Channel property error');
                event.Source.Value = event.PreviousValue;
            end
            
            % Make sure shown channel property values are not stale, as some property update can trigger changes in other properties
            % Update UI with current channel property values from DAQ 
            currentRange = app.DAQ.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange.Min currentRange.Max];
            app.TerminalConfigDropDown.Value = app.DAQ.Channels(1).TerminalConfig;
            app.CouplingDropDown.Value = app.DAQ.Channels(1).Coupling;            
        end

        % Value changed function: CaptureDurationEditField, 
        % ...and 1 other component
        function TriggerDelayEditFieldValueChanged(app, event)
            

        end

        % Close request function: MeasUIFigure
        function MeasUIFigureCloseRequest(app, event)
            % Enable the Menu in MainApp
            app.DialogApp.OKButton.Enable = 'on';

            % Set DialogApp view
            % Call DialogApp's public function
            app.DialogApp.DialogAppState = 'NewCondition';
            setDialogAppViewState(app.DialogApp, app.DialogApp.DialogAppState);

            % Delete this app
            delete(app)
        end

        % Value changed function: CaptureButton
        function CaptureButtonValueChanged(app, event)
            value = app.CaptureButton.Value;
            if ~value
                % Capture cancelled
                app.CurrentState = 'Acquisition.ReadyForCapture';
                setAppViewState(app, app.CurrentState);
            end
        end

        % Value changed function: TriggerChannelDropDown
        function TriggerChannelValueChanged(app, event)
            updateChannelMeasurementComponents(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasUIFigure = uifigure('Visible', 'off');
            app.MeasUIFigure.Name = 'Meas';
            app.MeasUIFigure.CloseRequestFcn = createCallbackFcn(app, @MeasUIFigureCloseRequest, true);
            app.MeasUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MeasUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DeviceLabel = uilabel(app.LeftPanel);
            app.DeviceLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.LeftPanel);
            app.DeviceDropDown.Items = {'Detecting devices...'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Detecting devices...';
            app.ChannelLabel = uilabel(app.LeftPanel);
            app.ChannelLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.MeasurementTypeLabel = uilabel(app.LeftPanel);
            app.MeasurementTypeLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.LeftPanel);
            app.MeasurementTypeDropDown.Items = {};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown.Value = {};
            app.RangeLabel = uilabel(app.LeftPanel);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.LeftPanel);
            app.RangeDropDown.Items = {};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.RangeDropDown.Value = {};
            app.CouplingLabel = uilabel(app.LeftPanel);
            app.CouplingLabel.Text = 'Coupling';
            app.CouplingDropDown = uidropdown(app.LeftPanel);
            app.CouplingDropDown.Items = {};
            app.CouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.CouplingDropDown.Value = {};
            app.TerminalConfigLabel = uilabel(app.LeftPanel);
            app.TerminalConfigLabel.Text = 'Terminal Config.';
            app.TerminalConfigDropDown = uidropdown(app.LeftPanel);
            app.TerminalConfigDropDown.Items = {};
            app.TerminalConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.TerminalConfigDropDown.Value = {};
            app.ExcitationSourceLabel = uilabel(app.LeftPanel);
            app.ExcitationSourceLabel.Text = 'Excitation Source';
            app.ExcitationSourceDropDown = uidropdown(app.LeftPanel);
            app.ExcitationSourceDropDown.Items = {};
            app.ExcitationSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.ExcitationSourceDropDown.Value = {};
            app.RatescanssLabel = uilabel(app.LeftPanel);
            app.RatescanssLabel.Text = 'Rate (scans/s)';
            app.RateEdit = uieditfield(app.LeftPanel, 'numeric');
            app.RateEdit.ValueDisplayFormat = '%.1f';
            app.RateEdit.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateEdit.Value = 1000;
            app.RateSlider = uislider(app.LeftPanel);
            app.RateSlider.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateSlider.ValueChangingFcn = createCallbackFcn(app, @RateSliderValueChanging, true);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LeftPanelLabel = uilabel(app.LeftPanel);
            app.LeftPanelLabel.Text = 'Device';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CaptureDataAxes = uiaxes(app.CenterPanel);
            title(app.CaptureDataAxes, 'Captured Data')
            xlabel(app.CaptureDataAxes, 'Time (s)')
            ylabel(app.CaptureDataAxes, 'Y')
            app.CenterPanelLabel = uilabel(app.CenterPanel);
            app.CenterPanelLabel.Text = 'Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TriggerConditionDropDownLabel = uilabel(app.RightPanel);
            app.TriggerConditionDropDownLabel.Text = 'Trigger Condition';
            app.TriggerConditionDropDown = uidropdown(app.RightPanel);
            app.TriggerConditionDropDown.Items = {'Rising', 'Falling'};
            app.TriggerConditionDropDown.Value = 'Rising';
            app.VariableNameEditFieldLabel = uilabel(app.RightPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.RightPanel, 'text');
            app.VariableNameEditField.Editable = 'off';
            app.VariableNameEditField.Value = 'expdata';
            app.CaptureButton = uibutton(app.RightPanel, 'state');
            app.CaptureButton.ValueChangedFcn = createCallbackFcn(app, @CaptureButtonValueChanged, true);
            app.CaptureButton.Text = 'Capture';
            app.StatusText = uilabel(app.RightPanel);
            app.StatusText.Text = '';
            app.TriggerLevelEditFieldLabel = uilabel(app.RightPanel);
            app.TriggerLevelEditFieldLabel.Text = 'Trigger Level';
            app.TriggerLevelEditField = uieditfield(app.RightPanel, 'numeric');
            app.TriggerLevelEditField.Value = 0.1;
            app.TriggerDelaysEditFieldLabel = uilabel(app.RightPanel);
            app.TriggerDelaysEditFieldLabel.Text = 'Trigger Delay (s)';
            app.TriggerDelayEditField = uieditfield(app.RightPanel, 'numeric');
            app.TriggerDelayEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerDelayEditFieldValueChanged, true);
            app.TriggerDelayEditField.Value = -0.5;
            app.CaptureDurationsEditFieldLabel = uilabel(app.RightPanel);
            app.CaptureDurationsEditFieldLabel.Text = 'Capture Duration (s)';
            app.CaptureDurationEditField = uieditfield(app.RightPanel, 'numeric');
            app.CaptureDurationEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerDelayEditFieldValueChanged, true);
            app.CaptureDurationEditField.Value = 0.05;
            app.RightPanelLabel = uilabel(app.RightPanel);
            app.RightPanelLabel.Text = 'Trigger';
            app.TriggerChannelLabel = uilabel(app.RightPanel);
            app.TriggerChannelLabel.Text = 'Trigger Channel';
            app.TriggerChannelDropDown = uidropdown(app.RightPanel);
            app.TriggerChannelDropDown.Items = {};
            app.TriggerChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerChannelValueChanged, true);
            app.TriggerChannelDropDown.Value = {};
            % Show the figure after all components are created
            app.MeasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeasApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasUIFigure)
        end
    end
end